classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button_13               matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Label_4                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Image5                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button_5                matlab.ui.control.ToggleButton
        Button_4                matlab.ui.control.ToggleButton
        Button_3                matlab.ui.control.ToggleButton
        Button_2                matlab.ui.control.ToggleButton
        Tab_3                   matlab.ui.container.Tab
        Label_28                matlab.ui.control.Label
        Image7_2                matlab.ui.control.Image
        TabGroup2               matlab.ui.container.TabGroup
        Tab_4                   matlab.ui.container.Tab
        cSpinner_2              matlab.ui.control.Spinner
        cSpinner_2Label         matlab.ui.control.Label
        gxyclog1fxyLabel        matlab.ui.control.Label
        Tab_5                   matlab.ui.container.Tab
        cSpinner                matlab.ui.control.Spinner
        cSpinnerLabel           matlab.ui.control.Label
        gxycefxy1Label          matlab.ui.control.Label
        Slider_3                matlab.ui.control.Slider
        Label_14                matlab.ui.control.Label
        Slider_2                matlab.ui.control.Slider
        Label_13                matlab.ui.control.Label
        Label_11                matlab.ui.control.Label
        Image6_2                matlab.ui.control.Image
        Tab_2                   matlab.ui.container.Tab
        ySlider                 matlab.ui.control.Slider
        ySliderLabel            matlab.ui.control.Label
        xSlider                 matlab.ui.control.Slider
        xSliderLabel            matlab.ui.control.Label
        Knob                    matlab.ui.control.Knob
        Label_27                matlab.ui.control.Label
        Image7                  matlab.ui.control.Image
        Label_8                 matlab.ui.control.Label
        Label_7                 matlab.ui.control.Label
        Image6                  matlab.ui.control.Image
        Slider                  matlab.ui.control.Slider
        Label_5                 matlab.ui.control.Label
        Tab_6                   matlab.ui.container.Tab
        Image6_5                matlab.ui.control.Image
        TabGroup4               matlab.ui.container.TabGroup
        Tab_11                  matlab.ui.container.Tab
        EditField_4             matlab.ui.control.EditField
        Image6_4                matlab.ui.control.Image
        Button_17               matlab.ui.control.Button
        Button_16               matlab.ui.control.Button
        Button_15               matlab.ui.control.Button
        Button_14               matlab.ui.control.Button
        Tab_12                  matlab.ui.container.Tab
        EditField_5             matlab.ui.control.EditField
        Button_18               matlab.ui.control.Button
        Spinner_3               matlab.ui.control.Spinner
        Label_31                matlab.ui.control.Label
        Spinner_2               matlab.ui.control.Spinner
        Label_30                matlab.ui.control.Label
        DropDown_5              matlab.ui.control.DropDown
        Label_29                matlab.ui.control.Label
        Image6_6                matlab.ui.control.Image
        TabGroup3               matlab.ui.container.TabGroup
        Tab_8                   matlab.ui.container.Tab
        varianceEditField       matlab.ui.control.NumericEditField
        varianceEditFieldLabel  matlab.ui.control.Label
        meanEditField           matlab.ui.control.NumericEditField
        meanEditFieldLabel      matlab.ui.control.Label
        Tab_9                   matlab.ui.container.Tab
        densitySpinner          matlab.ui.control.Spinner
        densitySpinnerLabel     matlab.ui.control.Label
        Tab_10                  matlab.ui.container.Tab
        Spinner                 matlab.ui.control.Spinner
        SpinnerLabel            matlab.ui.control.Label
        Tab_7                   matlab.ui.container.Tab
        DropDown_4              matlab.ui.control.DropDown
        Label_26                matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        Label_21                matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        Button_6                matlab.ui.control.Button
        Image11                 matlab.ui.control.Image
        Image10                 matlab.ui.control.Image
        EditField_3             matlab.ui.control.EditField
        Image                   matlab.ui.control.Image
    end

  
    properties (Access = public)
        Property2 % Description
        xScale = 1; % X 轴初始比例
        yScale = 1; % Y 轴初始比例
        width = 0;
        height = 0;
        image6 = '';
        gauss_mean = 0;
        gauss_v = 0.01;
        grayImg = '';
        originalHeight = 0;
        originalWidth = 0;
        img;
        imgb;
        imgc;
        imgd;
        FilterType;
        CutoffFrequency;
        FilterOrder;
    end

    
    methods (Access = private)
        
        function grayImg = myRgb2Gray(~, img)
            % 手动实现RGB转灰度的公式
            R = img(:,:,1);
            G = img(:,:,2);
            B = img(:,:,3);
            grayImg = 0.2989 * R + 0.5870 * G + 0.1140 * B; % 使用加权平均法
        end
        
        function myHist(~,img)
            % 计算直方图并显示
            [counts, grayLevels] = histcounts(img, 0:255); % 计算灰度级别的分布
            % 绘制直方图
            figure;
            bar(grayLevels(1:end-1), counts, 'histc');
            xlabel('灰度级');
            ylabel('像素数量');
            title('灰度直方图');
        end

        function matchedImg = myHistMatch(~,inputImg, refImg)
            % 转换为灰度图
            inputGray = rgb2gray(inputImg);
            refGray = rgb2gray(refImg);
        
            % 计算输入图像和参考图像的累积分布函数（CDF）
            [inputCounts, inputLevels] = histcounts(inputGray, 0:255);
            inputCDF = cumsum(inputCounts) / numel(inputGray);
        
            [refCounts, refLevels] = histcounts(refGray, 0:255);
            refCDF = cumsum(refCounts) / numel(refGray);
        
            % 为每个输入图像的灰度值查找匹配的参考灰度值
            matchedImg = inputGray;
            for i = 1:length(inputLevels)-1
                % 寻找最接近的参考CDF值
                [~, idx] = min(abs(inputCDF(i) - refCDF));  % 计算输入图像与参考图像的匹配
                matchedImg(inputGray == inputLevels(i)) = refLevels(idx);
            end
        end

        function equalizedImg = myHistogramEqualization(~,img)
            % 将图像像素值归一化到 [0, 1] 区间
            img = double(img) / 255;
            
            % 计算图像的直方图
            [counts, grayLevels] = imhist(uint8(img * 255));
            
            % 归一化直方图
            counts = counts / sum(counts);
            
            % 计算累积分布函数（CDF）
            cdf = cumsum(counts);
            
            % 将CDF映射到新的灰度级
            newGrayLevels = round(cdf * 255);
            
            % 使用新的灰度级来进行映射，生成均衡化后的图像
            equalizedImg = uint8(newGrayLevels(uint8(img * 255) + 1));
            
            % 将图像像素值转换回 [0, 255] 区间
            equalizedImg = uint8(equalizedImg);
        end

        % 将缩放后的图片嵌入固定尺寸的黑色背景
        function background = embedInBlackBackground(app, scaledImg)
            [scaledHeight, scaledWidth, numChannels] = size(scaledImg);
        
            % 创建黑色背景
            if numChannels == 3
                background = zeros(app.originalHeight, app.originalWidth, 3, 'uint8');
            else
                background = zeros(app.originalHeight, app.originalWidth, 'uint8');
            end
        
            % 判断比例是否大于 1
            if scaledHeight > app.originalHeight || scaledWidth > app.originalWidth
                % 放大模式 - 裁剪中心区域嵌入背景
                topCrop = floor((scaledHeight - app.originalHeight) / 2) + 1;
                leftCrop = floor((scaledWidth - app.originalWidth) / 2) + 1;
                croppedImg = scaledImg(topCrop:topCrop + app.originalHeight - 1, ...
                                       leftCrop:leftCrop + app.originalWidth - 1, :);
                background = croppedImg; % 完全填充背景
            else
                % 缩小模式 - 嵌入到黑色背景
                top = floor((app.originalHeight - scaledHeight) / 2) + 1;
                left = floor((app.originalWidth - scaledWidth) / 2) + 1;
                background(top:top + scaledHeight - 1, left:left + scaledWidth - 1, :) = scaledImg;
            end
        end
        
        
        % 保存图片并更新显示
        function saveAndDisplay(app, image, prefix)
            filename = [prefix, '_', num2str(now, '%.10f'), '.png'];
            imwrite(image, filename);
            app.Image6.ImageSource = filename;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            % 打开本地文件选择对话框
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                                      '选择一张图片');
            % 检查用户是否选择了文件
            if isequal(file, 0)
                return; % 如果没有选择文件，则退出回调函数
            end
    
            % 构建完整文件路径
            filePath = fullfile(path, file);
    
            % 在 Image 控件中显示选择的图片
            app.Image.ImageSource = filePath;
            timg = imread(filePath);
            app.img = timg;
            [app.originalHeight, app.originalWidth, ~] = size(timg);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            % 检查当前按钮并执行相应操作
            if selectedButton == app.Button_2
                % 灰度化
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
        
                % 读取图片并转换为灰度图
                img = imread(app.Image.ImageSource);
                mygray= myRgb2Gray(app,img);
                gray = rgb2gray(img);
                % 保存并显示结果
                outputFile = ['mygray_result_', num2str(now, '%.10f'), '.png'];
                imwrite(mygray, outputFile);
                output = ['gray_result_', num2str(now, '%.10f'), '.png'];
                imwrite(gray, output);
                app.Image2.ImageSource = outputFile;
                app.Image3.ImageSource = output;
                app.grayImg = outputFile;
            
            elseif selectedButton == app.Button_3
                % 直方图显示
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
                if isempty(app.grayImg)
                    uialert(app.UIFigure, '请先进行灰度化！', '错误');
                    return;
                end
        
                % 读取图片并绘制直方图
                img = imread(app.grayImg);
                myHist(app,img); % 使用自定义直方图显示函数
                histFile = ['histogram_result_', num2str(now, '%.10f'), '.png'];
                saveas(gcf, histFile); % 保存为图片
                app.Image3.ImageSource = histFile;
        
            elseif selectedButton == app.Button_4
                % 直方图均衡化
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
                if isempty(app.grayImg)
                    uialert(app.UIFigure, '请先进行灰度化！', '错误');
                    return;
                end
                % 读取图片并均衡化
                img = imread(app.grayImg);
                equalizedImg = myHistogramEqualization(app,img); % 使用自定义均衡化函数
        
                % 保存均衡化图像
                equalizedFile = 'equalized_result.png';
                imwrite(equalizedImg, equalizedFile);
%                 app.Image4.ImageSource = equalizedFile;
        
                % 保存均衡化后的直方图
                histEqFile = ['equalized_histogram_', num2str(now, '%.10f'), '.png'];
                figure; imhist(equalizedImg); % 绘制直方图
                saveas(gcf, histEqFile); % 保存图片
                app.Image4.ImageSource = histEqFile;
        
            elseif selectedButton == app.Button_5
                % 直方图匹配（规定化）
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
        
                gray = imread(app.grayImg);
               % 生成随机参考灰度图像
                referenceGray = uint8(255 * rand(size(gray))); % 大小与输入图像相同
                
                % 匹配直方图
                matchedImg = imhistmatch(gray, referenceGray);
        
                % 保存匹配后图像
                matchedFile = 'matched_result.png';
                imwrite(matchedImg, matchedFile);
                %app.Image5.ImageSource = matchedFile;
        
                % 保存匹配后的直方图
                matchedHistFile = ['matched_histogram_', num2str(now, '%.10f'), '.png'];
                figure; imhist(matchedImg); % 绘制直方图
                saveas(gcf, matchedHistFile); % 保存图片
                app.Image5.ImageSource = matchedHistFile;
        
            else
                % 未知操作
                uialert(app.UIFigure, '未知操作', '错误');
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            
            % 如果 app.img 为空，则读取原始图像
            if isempty(app.img)
                timg = imread(app.Image.ImageSource); % 使用原始图片进行第一次按比例缩放
            else
                timg = app.img; % 使用 app.img 中的变换后的图片
            end
            
            % 计算缩放比例
            if value <= 100
                scaleValue = value / 100; % 缩小比例
            else
                scaleValue = value / 100; % 放大比例
            end
            
            % 缩放图片
            scaledImg = imresize(timg, scaleValue);
            [scaledHeight, scaledWidth, ~] = size(scaledImg);
            
            % 将缩放后的图像存储到 app.img
            app.imgb = scaledImg;
            
            % 创建黑色背景并嵌入图片
            background = embedInBlackBackground(app, scaledImg);
            
            % 保存并更新显示
            scaledFile = ['scaled_image_', num2str(now, '%.10f'), '.png'];
            imwrite(background, scaledFile);
            app.Image6.ImageSource = scaledFile;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
    
            % 读取图片并进行旋转
            img = imread(app.Image.ImageSource);
            angle = -value; % 获取输入的角度值
            rotatedImg = imrotate(img, angle); % 旋转图像
            
            % 保存并更新 Image7 显示（使用唯一文件名强制刷新）
            rotatedFile = ['rotated_image_', num2str(now, '%.10f'), '.png'];
            imwrite(rotatedImg, rotatedFile);
            app.Image7.ImageSource = rotatedFile;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            brightnessValue = app.Slider_2.Value; % 获取亮度值
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            % 加载图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转为 double 类型方便计算
            % 应用亮度调整
            imgLinear = img + brightnessValue / 100;
            imgLinear = imgLinear * (1 + app.Slider_3.Value / 100); % 当前对比度值
            imgLinear = min(max(imgLinear, 0), 1); % 限制在 [0,1] 范围
            % 更新线性变换结果显示
            linearFile = ['linear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgLinear, linearFile);
            app.Image6_2.ImageSource = linearFile;
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            contrastValue = app.Slider_3.Value; % 获取对比度值
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            % 加载图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转为 double 类型方便计算
            % 应用对比度调整
            imgLinear = img + app.Slider_2.Value / 100; % 当前亮度值
            imgLinear = imgLinear * (1 + contrastValue / 100);
            imgLinear = min(max(imgLinear, 0), 1); % 限制在 [0,1] 范围
            % 更新线性变换结果显示
            linearFile = ['linear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgLinear, linearFile);
            app.Image6_2.ImageSource = linearFile;
        end

        % Callback function
        function EditField_2ValueChanged(app, event)

            % 获取输入值，用于控制噪声类型
            noiseType = app.EditField_2.Value;
        
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
        
            % 加载原始图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转换为 double 类型方便处理
        
            % 加噪处理
            if noiseType == 1
                % 高斯噪声
                noisyImg = imnoise(img, 'gaussian', 0, 0.01);
            elseif noiseType == 2
                % 盐椒噪声
                noisyImg = imnoise(img, 'salt & pepper', 0.02);
            elseif noiseType == 3
                % 均匀噪声
                noisyImg = img + 0.1 * (rand(size(img)) - 0.5);
                noisyImg = min(max(noisyImg, 0), 1); % 限制范围到 [0,1]
            else
                uialert(app.UIFigure, '请输入有效的噪声类型值（1: 高斯, 2: 盐椒, 3: 均匀）！', '错误');
                return;
            end
        
            % 更新加噪图像显示
            noisyFile = ['noisy_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_3.ImageSource = noisyFile;
        
            % 空域滤波（中值滤波）
            filteredImgSpatial = medfilt2(rgb2gray(noisyImg), [3 3]); % 灰度处理后中值滤波
            spatialFile = ['spatial_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImgSpatial, spatialFile);
            app.Image8.ImageSource = spatialFile;
        
            % 频域滤波（理想低通滤波）
            [rows, cols] = size(rgb2gray(noisyImg)); % 获取图像大小
            D0 = 30; % 截止频率
            [U, V] = meshgrid(1:cols, 1:rows);
            D = sqrt((U - cols / 2).^2 + (V - rows / 2).^2);
            H = double(D <= D0); % 低通滤波器
            
            % 频域处理
            fftImg = fftshift(fft2(rgb2gray(noisyImg)));
            filteredImgFreq = real(ifft2(ifftshift(fftImg .* H)));
            filteredImgFreq = mat2gray(filteredImgFreq); % 归一化
            
            freqFile = ['frequency_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImgFreq, freqFile);
            app.Image9.ImageSource = freqFile;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
    
            % 读取原图像
            img = imread(app.Image.ImageSource); % 从app.Image.ImageSource获取原图像路径
            img_gray = rgb2gray(img); % 将图像转为灰度图像
        
            % 根据选择的算子进行边缘检测
            switch value
                case '罗伯特算子'
                    % 使用Robert算子进行边缘检测
                    edge_img = edge(img_gray, 'Roberts');
                case 'Prewitt算子'
                    % 使用Prewitt算子进行边缘检测
                    edge_img = edge(img_gray, 'Prewitt');
                case 'Sobel算子'
                    % 使用Sobel算子进行边缘检测
                    edge_img = edge(img_gray, 'Sobel');
                case '拉普拉斯算子'
                    % 使用拉普拉斯算子进行边缘检测
                    edge_img = edge(img_gray, 'log'); % 'log'表示拉普拉斯算子
                otherwise
                    % 默认行为
                    edge_img = img_gray;
            end
            
            % 保存边缘检测图像并更新Image10
            edgeFile = ['edge_image_', num2str(now, '%.10f'), '.png'];
            imwrite(edge_img, edgeFile); % 保存边缘检测图像
            app.Image10.ImageSource = edgeFile; % 更新Image10控件的ImageSource为边缘图像
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 读取原图像
            img = imread(app.Image.ImageSource);  % 获取原图像
            
            % 转为灰度图像
            img_gray = rgb2gray(img);
            
            % 目标提取（基于阈值的简单分割示例，您可以根据需要替换为更复杂的算法）
            threshold = 0.5;  % 设置阈值
            binary_img = imbinarize(img_gray, threshold);  % 二值化图像进行目标提取
            
            % 进行形态学操作去除噪声（可选）
            se = strel('disk', 5);  % 结构元素
            binary_img = imopen(binary_img, se);  % 开运算去噪
            
            % 保存目标提取图像并更新Image11
            targetFile = ['target_image_', num2str(now, '%.10f'), '.png'];
            imwrite(binary_img, targetFile);  % 保存目标提取图像
            app.Image11.ImageSource = targetFile;  % 更新Image11控件的ImageSource为目标提取结果
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;  % 获取下拉框选项

            % 读取原图像和目标提取图像
            img = imread(app.Image.ImageSource);  % 获取原图像
            img_gray = rgb2gray(img);  % 将图像转为灰度图像
            target_img = imread(app.Image11.ImageSource);  % 获取目标提取后的图像
            
            % 特征提取结果初始化
            originalFeatures = [];
            targetFeatures = [];
            
            % 根据选择的特征提取方法进行特征提取
            switch value
                case 'LBP'
                    % LBP特征提取
                    originalFeatures = extractLBPFeatures(img_gray);
                    targetFeatures = extractLBPFeatures(target_img);
                case 'HOG'
                    % HOG特征提取
                    [originalFeatures, ~] = extractHOGFeatures(img_gray);
                    [targetFeatures, ~] = extractHOGFeatures(target_img);
            end
        
            % 更新TextArea显示特征提取结果
            app.TextArea.Value = sprintf("Original Features: %s\nTarget Features: %s", ...
            mat2str(originalFeatures, 5), mat2str(targetFeatures, 5));
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            disp(['Text Area updated: ', value]);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
    
            % 读取图片并进行旋转
            img = imread(app.Image.ImageSource);
            angle = value; % 获取输入的角度值
            rotatedImg = imrotate(img, angle); % 旋转图像
            
            % 保存并更新 Image7 显示（使用唯一文件名强制刷新）
            rotatedFile = ['rotated_image_', num2str(now, '%.10f'), '.png'];
            imwrite(rotatedImg, rotatedFile);
            app.Image7.ImageSource = rotatedFile;
        end

        % Value changed function: cSpinner_2
        function cSpinner_2ValueChanged(app, event)
            value = app.cSpinner_2.Value;
            
            % 加载彩色图像
            img = imread(app.Image.ImageSource); 
            img = im2double(img); % 转为 double 类型方便计算
        
            % 获取图像的三个颜色通道：R, G, B
            R = img(:,:,1); % 红色通道
            G = img(:,:,2); % 绿色通道
            B = img(:,:,3); % 蓝色通道
        
            % 定义对数变换公式，分别对每个通道进行变换
            c = value; % 使用编辑框中的常数作为 c
            R = c * log(1 + R); % 对红色通道应用对数变换
            G = c * log(1 + G); % 对绿色通道应用对数变换
            B = c * log(1 + B); % 对蓝色通道应用对数变换
        
            % 限制输出像素值在 [0, 1] 范围内
            R = mat2gray(R); 
            G = mat2gray(G); 
            B = mat2gray(B); 
        
            % 合并变换后的颜色通道
            imgNonlinear = cat(3, R, G, B); 
        
            % 更新非线性变换结果显示
            nonlinearFile = ['nonlinear0_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgNonlinear, nonlinearFile); % 保存变换后的图像
        
            % 更新界面上的显示图像
            app.Image7_2.ImageSource = nonlinearFile;
        end

        % Value changed function: cSpinner
        function cSpinnerValueChanged(app, event)
            value = app.cSpinner.Value;
            % 加载彩色图像
            img = imread(app.Image.ImageSource); 
            img = im2double(img); % 转为 double 类型方便计算
        
            % 获取图像的三个颜色通道：R, G, B
            R = img(:,:,1); % 红色通道
            G = img(:,:,2); % 绿色通道
            B = img(:,:,3); % 蓝色通道
        
            % 定义指数变换公式，分别对每个通道进行变换
            c = value; % 使用编辑框中的常数作为 c
            R = c * (exp(R) - 1); % 对红色通道应用指数变换
            G = c * (exp(G) - 1); % 对绿色通道应用指数变换
            B = c * (exp(B) - 1); % 对蓝色通道应用指数变换
         
            % 合并变换后的颜色通道
            imgNonlinear = cat(3, R, G, B); 
        
            % 更新非线性变换结果显示
            nonlinearFile = ['nonlinear1_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgNonlinear, nonlinearFile); % 保存变换后的图像
        
            % 更新界面上的显示图像
            app.Image7_2.ImageSource = nonlinearFile;
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            xScaleValue = app.xSlider.Value / 100; % X 轴缩放比例
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
        
            if isempty(app.img)
                img = imread(app.Image.ImageSource); % 第一次变换时使用原始图像
            else
                if isempty(app.imgc)
                    if isempty(app.imgb)
                        img = app.img; % 第一次变换时使用原始图像
                    else
                        img = app.imgb;
                    end
                else
                    img = app.imgc;
                end
            end
            
            % 读取原始图片尺寸
            [origHeight, origWidth, numChannels] = size(img);
            
            % 按 X 轴缩放
            newWidth = round(origWidth * xScaleValue);
            scaledImg = imresize(img, [origHeight, newWidth]); % 宽度缩放，保持高度不变
            [scaledHeight, scaledWidth, ~] = size(scaledImg);
            
            % 将变换后的图像存储到 app.img
            app.imgc = scaledImg;
            
            % 创建黑色背景并嵌入图片
            background = embedInBlackBackground(app, scaledImg);
            
            % 保存并更新显示
            scaledFile = ['x_scaled_image_', num2str(now, '%.10f'), '.png'];
            imwrite(background, scaledFile);
            app.Image6.ImageSource = scaledFile;
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            yScaleValue = app.ySlider.Value / 100; % Y 轴缩放比例
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
        
            if isempty(app.img)
                img = imread(app.Image.ImageSource); % 第一次变换时使用原始图像
            else
                if isempty(app.imgb)
                    img = app.img; % 第一次变换时使用原始图像
                else
                    img = app.imgb;
                end
            end
            
            % 读取原始图片尺寸
            [origHeight, origWidth, numChannels] = size(img);
            
            % 按 Y 轴缩放
            newHeight = round(origHeight * yScaleValue);
            scaledImg = imresize(img, [newHeight, origWidth]); % 高度缩放，保持宽度不变
            [scaledHeight, scaledWidth, ~] = size(scaledImg);
            
            % 将变换后的图像存储到 app.img
            app.img = scaledImg;
            
            % 创建黑色背景并嵌入图片
            background = embedInBlackBackground(app, scaledImg);
            
            % 保存并更新显示
            scaledFile = ['y_scaled_image_', num2str(now, '%.10f'), '.png'];
            imwrite(background, scaledFile);
            app.Image6.ImageSource = scaledFile;
        end

        % Value changed function: meanEditField
        function meanEditFieldValueChanged(app, event)
            value = app.meanEditField.Value;
            app.gauss_mean = value;
            img = imread(app.Image.ImageSource);
            img = im2double(img);
            noisyImg = imnoise(img, 'gaussian', app.gauss_mean , app.gauss_v);
            % 更新加噪图像显示
            noisyFile = ['noisy_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_5.ImageSource = noisyFile;
        end

        % Value changed function: varianceEditField
        function varianceEditFieldValueChanged(app, event)
            value = app.varianceEditField.Value;
            app.gauss_v = value;
            img = imread(app.Image.ImageSource);
            img = im2double(img);
            noisyImg = imnoise(img, 'gaussian', app.gauss_mean , app.gauss_v);
            % 更新加噪图像显示
            noisyFile = ['noisy_gauss_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_5.ImageSource = noisyFile;
        end

        % Value changed function: densitySpinner
        function densitySpinnerValueChanged(app, event)
            value = app.densitySpinner.Value;
            img = imread(app.Image.ImageSource);
            img = im2double(img);
            noisyImg = imnoise(img, 'salt & pepper', value);
            % 更新加噪图像显示
            noisyFile = ['noisy_density_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_5.ImageSource = noisyFile;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            img = imread(app.Image.ImageSource);
            img = im2double(img);
            noisyImg = img + value * (rand(size(img)) - 0.5);
            noisyImg = min(max(noisyImg, 0), 1); % 限制范围到 [0,1]
            % 更新加噪图像显示
            noisyFile = ['noisy_spinner_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_5.ImageSource = noisyFile;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            % 读取图片
            img = imread(app.Image.ImageSource);
        
            % 预处理图片
            img = imresize(img, [448, 448]);           % 调整为模型需要的输入尺寸
            img = double(img) / 255;                  % 归一化到 [0, 1]
            img = reshape(img, [448, 448, 3]);        % 确保为 HWC 格式（高度、宽度、通道）
        
            % 加载 ONNX 模型
            onnxModel = importONNXNetwork('model.onnx', 'OutputLayerType', 'classification');
        
            % 使用 ONNX 模型进行预测
            output = predict(onnxModel, img);          % 输入为 HWC 格式
            [~, predictedClass] = max(output, [], 2); % 获取预测结果
        
            % 显示预测结果
            app.EditField_3.Value = ['预测标签：', num2str(predictedClass)];
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            % 读取图像
            img = imread(app.Image6_5.ImageSource);
            
            % 如果是彩色图像，分别处理每个通道
            if size(img, 3) == 3
                % 增大滤波器尺寸，对每个颜色通道分别进行均值滤波
                redChannel = imfilter(img(:,:,1), fspecial('average', [7, 7]), 'symmetric');
                greenChannel = imfilter(img(:,:,2), fspecial('average', [7, 7]), 'symmetric');
                blueChannel = imfilter(img(:,:,3), fspecial('average', [7, 7]), 'symmetric');
                
                % 重新组合滤波后的通道
                filteredImg = cat(3, redChannel, greenChannel, blueChannel);
            else
                % 对灰度图像直接应用均值滤波
                filteredImg = imfilter(img, fspecial('average', [7, 7]), 'symmetric');
            end
            
            % 输出结果
            noisyFile = ['mean_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImg, noisyFile);
            app.Image6_4.ImageSource = noisyFile;  % 更新显示
            app.EditField_4.Value = '均值滤波';
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            % 读取图像
            img = imread(app.Image6_5.ImageSource);
            
            % 如果是彩色图像，分别处理每个通道
            if size(img, 3) == 3
                % 增大滤波器尺寸，适当增加标准差，对每个颜色通道分别进行高斯滤波
                redChannel = imfilter(img(:,:,1), fspecial('gaussian', [7, 7], 1.5), 'symmetric');
                greenChannel = imfilter(img(:,:,2), fspecial('gaussian', [7, 7], 1.5), 'symmetric');
                blueChannel = imfilter(img(:,:,3), fspecial('gaussian', [7, 7], 1.5), 'symmetric');
                
                % 重新组合滤波后的通道
                filteredImg = cat(3, redChannel, greenChannel, blueChannel);
            else
                % 对灰度图像直接应用高斯滤波
                filteredImg = imfilter(img, fspecial('gaussian', [7, 7], 1.5), 'symmetric');
            end
            
            % 输出结果
            noisyFile = ['gaussian_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImg, noisyFile);
            app.Image6_4.ImageSource = noisyFile;  % 更新显示
            app.EditField_4.Value = '高斯滤波';
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            % 读取图像
            img = imread(app.Image6_5.ImageSource);
            
            % 如果是彩色图像，分别处理每个通道
            if size(img, 3) == 3
                % 对每个颜色通道分别进行中值滤波
                redChannel = medfilt2(img(:,:,1), [3, 3]);
                greenChannel = medfilt2(img(:,:,2), [3, 3]);
                blueChannel = medfilt2(img(:,:,3), [3, 3]);
                
                % 重新组合滤波后的通道
                filteredImg = cat(3, redChannel, greenChannel, blueChannel);
            else
                % 对灰度图像直接应用中值滤波
                filteredImg = medfilt2(img, [3, 3]);
            end
            
            % 输出结果
            noisyFile = ['median_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImg, noisyFile);
            app.Image6_4.ImageSource = noisyFile;  % 更新显示
            app.EditField_4.Value = '中值滤波';
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            % 读取图像
            img = imread(app.Image6_5.ImageSource);
            
            % 如果是彩色图像，直接使用双边滤波处理
            if size(img, 3) == 3
                % 对彩色图像进行双边滤波
                filteredImg = imbilatfilt(img, 2, 20);
            else
                % 对灰度图像直接应用双边滤波
                filteredImg = imbilatfilt(img, 2, 20);
            end
            
            % 输出结果
            noisyFile = ['bilateral_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImg, noisyFile);
            app.Image6_4.ImageSource = noisyFile;  % 更新显示
            app.EditField_4.Value = '双边滤波';
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            app.FilterType = value; % 保存选择的滤波器类型
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            % 读取图像
            img = imread(app.Image6_5.ImageSource);
        
            % 如果是彩色图像，转换为灰度图像
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
        
            % 获取频域滤波器类型、截止频率和滤波器阶数
            filterType = char(app.FilterType); % 转换为字符向量以兼容 switch
            D0 = app.CutoffFrequency; % 截止频率
            n = app.FilterOrder; % 滤波器阶数
        
            % 图像大小
            [M, N] = size(img);
            [U, V] = meshgrid(1:N, 1:M);
        
            % 将频率中心移动到图像中心
            D = sqrt((U - N/2).^2 + (V - M/2).^2);
        
            % 创建滤波器
            switch filterType
                case '低通滤波器'
                    H = 1 ./ (1 + (D / D0).^(2 * n)); % 巴特沃斯低通滤波器
                case '高通滤波器'
                    H = 1 ./ (1 + (D0 ./ D).^(2 * n)); % 巴特沃斯高通滤波器
                case '带通滤波器'
                    % 设置带通频率范围
                    D_low = max(D0 - 10, 0); % 下截止频率
                    D_high = D0 + 10; % 上截止频率
                    H = 1 ./ (1 + (D / D_high).^(2 * n)) .* (1 - 1 ./ (1 + (D / D_low).^(2 * n)));
                case '带阻滤波器'
                    % 设置带阻频率范围
                    D_low = max(D0 - 10, 0); % 下截止频率
                    D_high = D0 + 10; % 上截止频率
                    H = 1 - (1 ./ (1 + (D / D_high).^(2 * n)) .* (1 - 1 ./ (1 + (D / D_low).^(2 * n))));
                otherwise
                    H = ones(M, N); % 默认全通过
            end
        
            % 进行傅里叶变换
            F = fft2(double(img));
            F_shifted = fftshift(F);
        
            % 应用滤波器
            F_filtered = F_shifted .* H;
        
            % 进行逆傅里叶变换
            F_ishifted = ifftshift(F_filtered);
            img_filtered = real(ifft2(F_ishifted));
        
            % 归一化到[0, 255]
            img_filtered = uint8(255 * mat2gray(img_filtered));
        
            % 显示结果
            resultFile = ['freq_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(img_filtered, resultFile);
            app.Image6_6.ImageSource = resultFile; % 更新显示
            % 格式化输出滤波器类型、截止频率、滤波器阶数
            outputMessage = sprintf('滤波器类型: %s, 截止频率: %.2f, 滤波器阶数: %d',app.FilterType, D0, n);
            app.EditField_5.Value = outputMessage; % 更新文本框
        end

        % Value changing function: Spinner_3
        function Spinner_3ValueChanging(app, event)
            changingValue = event.Value;
            app.FilterOrder = changingValue; % 保存滤波器阶数
        end

        % Value changing function: Spinner_2
        function Spinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.CutoffFrequency = changingValue; % 保存截止频率 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像处理';
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '灰度化';
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '直方图显示';
            app.Button_3.Value = true;
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = '直方图均衡化';
            app.Button_5 = uitogglebutton(app.ButtonGroup);
            app.Button_5.Text = '直方图匹配';
            app.Image2 = uiimage(app.Tab);
            app.Image3 = uiimage(app.Tab);
            app.Image4 = uiimage(app.Tab);
            app.Image5 = uiimage(app.Tab);
            app.Label = uilabel(app.Tab);
            app.Label.Text = '灰度图像';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '直方图';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '直方图均衡化';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '直方图匹配';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '对比度增强';
            app.Image6_2 = uiimage(app.Tab_3);
            app.Label_11 = uilabel(app.Tab_3);
            app.Label_11.Text = '线性变换';
            app.Label_13 = uilabel(app.Tab_3);
            app.Label_13.Text = '亮度';
            app.Slider_2 = uislider(app.Tab_3);
            app.Slider_2.Limits = [-100 100];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_14 = uilabel(app.Tab_3);
            app.Label_14.Text = '对比度';
            app.Slider_3 = uislider(app.Tab_3);
            app.Slider_3.Limits = [-100 100];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.TabGroup2 = uitabgroup(app.Tab_3);
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '对数变换';
            app.gxyclog1fxyLabel = uilabel(app.Tab_4);
            app.gxyclog1fxyLabel.Text = 'g(x,y)=c⋅log(1+f(x,y))';
            app.cSpinner_2Label = uilabel(app.Tab_4);
            app.cSpinner_2Label.Text = 'c';
            app.cSpinner_2 = uispinner(app.Tab_4);
            app.cSpinner_2.Step = 0.5;
            app.cSpinner_2.Limits = [0 15];
            app.cSpinner_2.ValueChangedFcn = createCallbackFcn(app, @cSpinner_2ValueChanged, true);
            app.cSpinner_2.Value = 1;
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '指数变换';
            app.gxycefxy1Label = uilabel(app.Tab_5);
            app.gxycefxy1Label.Text = 'g(x,y)= c⋅(e^f(x,y) -1)';
            app.cSpinnerLabel = uilabel(app.Tab_5);
            app.cSpinnerLabel.Text = 'c';
            app.cSpinner = uispinner(app.Tab_5);
            app.cSpinner.Step = 0.5;
            app.cSpinner.Limits = [0 15];
            app.cSpinner.ValueChangedFcn = createCallbackFcn(app, @cSpinnerValueChanged, true);
            app.cSpinner.Value = 1;
            app.Image7_2 = uiimage(app.Tab_3);
            app.Label_28 = uilabel(app.Tab_3);
            app.Label_28.Text = '非线性变换';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '图像变换';
            app.Label_5 = uilabel(app.Tab_2);
            app.Label_5.Text = '按比例缩放(%)';
            app.Slider = uislider(app.Tab_2);
            app.Slider.Limits = [1 200];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 100;
            app.Image6 = uiimage(app.Tab_2);
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '缩放变换';
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '旋转变换';
            app.Image7 = uiimage(app.Tab_2);
            app.Label_27 = uilabel(app.Tab_2);
            app.Label_27.Text = '旋转角度';
            app.Knob = uiknob(app.Tab_2, 'continuous');
            app.Knob.Limits = [-180 180];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.xSliderLabel = uilabel(app.Tab_2);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.Tab_2);
            app.xSlider.Limits = [1 200];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.Value = 100;
            app.ySliderLabel = uilabel(app.Tab_2);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.Tab_2);
            app.ySlider.Limits = [1 200];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.Value = 100;
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '图像加噪';
            app.TabGroup3 = uitabgroup(app.Tab_6);
            app.Tab_8 = uitab(app.TabGroup3);
            app.Tab_8.Title = '高斯噪声';
            app.meanEditFieldLabel = uilabel(app.Tab_8);
            app.meanEditFieldLabel.Text = '均值 (mean)';
            app.meanEditField = uieditfield(app.Tab_8, 'numeric');
            app.meanEditField.ValueChangedFcn = createCallbackFcn(app, @meanEditFieldValueChanged, true);
            app.varianceEditFieldLabel = uilabel(app.Tab_8);
            app.varianceEditFieldLabel.Text = '方差 (variance)';
            app.varianceEditField = uieditfield(app.Tab_8, 'numeric');
            app.varianceEditField.ValueChangedFcn = createCallbackFcn(app, @varianceEditFieldValueChanged, true);
            app.Tab_9 = uitab(app.TabGroup3);
            app.Tab_9.Title = '盐椒噪声';
            app.densitySpinnerLabel = uilabel(app.Tab_9);
            app.densitySpinnerLabel.Text = '噪声密度 (density)';
            app.densitySpinner = uispinner(app.Tab_9);
            app.densitySpinner.Step = 0.01;
            app.densitySpinner.Limits = [0 1];
            app.densitySpinner.ValueChangedFcn = createCallbackFcn(app, @densitySpinnerValueChanged, true);
            app.Tab_10 = uitab(app.TabGroup3);
            app.Tab_10.Title = '均匀噪声';
            app.SpinnerLabel = uilabel(app.Tab_10);
            app.SpinnerLabel.Text = '噪声强度（系数）';
            app.Spinner = uispinner(app.Tab_10);
            app.Spinner.Limits = [-10 10];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.TabGroup4 = uitabgroup(app.Tab_6);
            app.Tab_11 = uitab(app.TabGroup4);
            app.Tab_11.Title = '空域滤波';
            app.Button_14 = uibutton(app.Tab_11, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '均值滤波';
            app.Button_15 = uibutton(app.Tab_11, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '高斯滤波';
            app.Button_16 = uibutton(app.Tab_11, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '中值滤波';
            app.Button_17 = uibutton(app.Tab_11, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '双边滤波';
            app.Image6_4 = uiimage(app.Tab_11);
            app.EditField_4 = uieditfield(app.Tab_11, 'text');
            app.Tab_12 = uitab(app.TabGroup4);
            app.Tab_12.Title = '频域滤波';
            app.Image6_6 = uiimage(app.Tab_12);
            app.Label_29 = uilabel(app.Tab_12);
            app.Label_29.Text = '滤波器类型';
            app.DropDown_5 = uidropdown(app.Tab_12);
            app.DropDown_5.Items = {'低通滤波器', '高通滤波器', '带通滤波器', '带阻滤波器'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '低通滤波器';
            app.Label_30 = uilabel(app.Tab_12);
            app.Label_30.Text = '截止频率';
            app.Spinner_2 = uispinner(app.Tab_12);
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @Spinner_2ValueChanging, true);
            app.Spinner_2.Limits = [10 100];
            app.Spinner_2.Value = 10;
            app.Label_31 = uilabel(app.Tab_12);
            app.Label_31.Text = '滤波器阶数';
            app.Spinner_3 = uispinner(app.Tab_12);
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @Spinner_3ValueChanging, true);
            app.Spinner_3.Limits = [1 10];
            app.Spinner_3.Value = 1;
            app.Button_18 = uibutton(app.Tab_12, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '频域滤波';
            app.EditField_5 = uieditfield(app.Tab_12, 'text');
            app.Image6_5 = uiimage(app.Tab_6);
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '边缘检测';
            app.Image10 = uiimage(app.Tab_7);
            app.Image11 = uiimage(app.Tab_7);
            app.Button_6 = uibutton(app.Tab_7, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '目标提取';
            app.TextArea = uitextarea(app.Tab_7);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Label_21 = uilabel(app.Tab_7);
            app.Label_21.Text = '边缘检测';
            app.DropDown = uidropdown(app.Tab_7);
            app.DropDown.Items = {'罗伯特算子', 'Prewitt算子', 'Sobel算子', '拉普拉斯算子'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '罗伯特算子';
            app.Label_26 = uilabel(app.Tab_7);
            app.Label_26.Text = '特征提取';
            app.DropDown_4 = uidropdown(app.Tab_7);
            app.DropDown_4.Items = {'LBP', 'HOG'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'LBP';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '图像分类';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end